classdef finoptimizerACcopy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        FinDragOptimizerwithConstrainedStabilityMarginLabel  matlab.ui.control.Label
        MachEditFieldLabel             matlab.ui.control.Label
        MachEditField                  matlab.ui.control.NumericEditField
        ReynoldsEditFieldLabel         matlab.ui.control.Label
        ReynoldsField                  matlab.ui.control.NumericEditField
        OUTPUTSLabel                   matlab.ui.control.Label
        INPUTSLabel                    matlab.ui.control.Label
        FinDragCoefficientEditFieldLabel  matlab.ui.control.Label
        FinDragCoefficientField        matlab.ui.control.NumericEditField
        RootChordmEditFieldLabel       matlab.ui.control.Label
        RootChordField                 matlab.ui.control.NumericEditField
        TipChordmEditFieldLabel        matlab.ui.control.Label
        TipChordField                  matlab.ui.control.NumericEditField
        SweepDistancemEditFieldLabel   matlab.ui.control.Label
        SweepDistanceField             matlab.ui.control.NumericEditField
        SemiSpanmEditFieldLabel        matlab.ui.control.Label
        SemiSpanField                  matlab.ui.control.NumericEditField
        StabilityMarginEditFieldLabel  matlab.ui.control.Label
        StabilityMarginEditField       matlab.ui.control.NumericEditField
        FinExposedAreaEditFieldLabel   matlab.ui.control.Label
        FinExposedAreaEditField        matlab.ui.control.NumericEditField
    end

    
    methods (Access = private)
        
        
        function result = test(app)
            X0 = [0.1;0.1;0.1;0.1]; %Initial Conditions for [a,b,m,s]
            A = [-0.75,1,0,0;
            0,-0.9,1,0;
            -1,0,0,1];
            B = [0;0;0];
            Aeq = [];
            Beq = [];
            LB = [0;0;0;0];
            UB = [Inf;Inf;Inf;Inf];

            X = fmincon(@(X) FUN(app,X),X0,A,B,Aeq,Beq,LB,UB,@(X) NONLCON(app,X))
            % FUN = Fin Drag Equation (Subsonic)

            % M = CP_FINAL(app,X);
            app.FinDragCoefficientField.Value = DRAG(app,X)
            
            app.RootChordField.Value = X(1);
            app.TipChordField.Value = X(2);
            app.SweepDistanceField.Value = X(3);
            app.SemiSpanField.Value = X(4);
            
            
            graph_vec = [0,0;X(3),X(4);(X(3)+X(2)),X(4);X(1),0];

            plot(app.UIAxes,graph_vec(1:4),graph_vec(5:8));
            xlim(app.UIAxes,[0,0.2]);
            ylim(app.UIAxes,[0,0.2]);
            
            result = 1;
        end
    
        function F = FUN(app,X)
% Constants (all dimensions in meters):
L_n = 0.8;    % Nose Length
F_r = 0.706;    % Fin Root Chord
F_t = 0.427;    % Fin Tip Chord
F_s = 0.552;    % Fin Semi-Span
S = 0.100;      % Sweep Distance
L_r = 5.844;    % Rocket Length
L_z = 0.0762;   % Nozzle Length
N = 3;          % # of fins
Cn_n = 0.5;     % Nose Cone Coefficient
t = 0.00635;    % Max Fin Root Thickness
X_tc = 0.00635; % Distance from Fin Leading Edge to Max Thickness
L_red = 0.05995;% Length Reduction @ back
D_noz = 0.08204;% Nozzle Diameter
D_nos = 0.140;  % Nose Base Diameter
D_end = 0.1077; % End Diameter
F_w = 0.00381;  % Fin Thickness
F_fl = 0.21895; % Fin Front Length
Cg = 3.372354308; %Centre of Gravity 

% Geometric Dimensions
Afe = (1/2)*(X(1)+X(2))*X(4);
app.FinExposedAreaEditField.Value = Afe;
Afp = (1/2)*D_nos*X(1) + Afe; % Fin Planform Area (+ Body)
mid =  X(4)/cos(atan((X(3)+(X(2)/2)-(X(1)/2))/X(4))); % Midchord

%Reynolds Number
rho = 1.225; %sea level, 15degC (ISA) [kg/m^3]
Ma = 340.3; %sea level, 15degC [m/s]
V = app.MachEditField.Value*Ma;
mu = 1.802*(10^-5); % 15degC [kg/m*s]
Re = (rho*V*mid)/mu; % 1,072,936.44 > Re_c = 5x10^5
app.ReynoldsField.Value = Re;

B = (5*(10^5))*((0.074/(Re^(1/5)))-(1.328/sqrt(Re)));
Cff = (0.074/((Re)^(1/5)))-(B/Re);
Cdf = 2*Cff*(1+2*(F_w/mid))*(4*3*Afp/(pi*(D_nos^2))); 

Cdi = 2*Cff*(1+2*(F_w/mid))*(4*3*(Afp-Afe)/(pi*(D_nos^2)));

F = Cdf + Cdi;
end


        function [margin_final] = CP_FINAL(app,X)
% Constants (all dimensions in meters):
L_n = 0.548;    % Nose Length
F_r = 0.406;    % Fin Root Chord
F_t = 0.127;    % Fin Tip Chord
F_s = 0.152;    % Fin Semi-Span
S = 0.100;      % Sweep Distance
L_r = 5.844;    % Rocket Length
L_z = 0.0762;   % Nozzle Length
N = 3;          % # of fins
Cn_n = 0.5;     % Nose Cone Coefficient
t = 0.00635;    % Max Fin Root Thickness
X_tc = 0.00635; % Distance from Fin Leading Edge to Max Thickness
L_red = 0.05995;% Length Reduction @ back
D_noz = 0.08204;% Nozzle Diameter
D_nos = 0.140;  % Nose Base Diameter
D_end = 0.1077; % End Diameter
F_w = 0.00381;  % Fin Width (Thickness)
F_fl = 0.21895; % Fin Front Length
Cg = 3.372354308; %Centre of Gravity 

% X (distance from nose tip to component's Cp):
% NOTE: Normal Body and Shoulder Forces are neglected 
X_n = L_n/2;
X_b = L_r - L_red + (L_red/3)*(1+1/(1+D_nos/D_end));

% Component Coefficient of Normal Force (Cn)
Cn_n = 2;
Cn_b = 2*(((D_end/D_nos)^2)-1);

%Optimization Variables = X(1) = a, X(2) = b, X(3) = m, X(4) = s

% Using default values for all other variables,
% % we can cast final Cp position as cp(a,b,m,s):
mid =  X(4)/cos(atan((X(3)+(X(2)/2)-(X(1)/2))/X(4)));
l =  (L_r-X(1))-L_red;
int = 1+ (D_nos/2)/(X(4)+(D_nos/2));
c =  int*(4*N*(X(4)/D_nos)^2)/(1+sqrt(1+(2*mid/(X(1)+X(2)))^2));
x =  l+ (X(3)*(X(1)+2*X(2)))/(3*(X(1)+X(2))) + (1/6)*(X(1)+X(2)-(X(1)*X(2))/(X(1)+X(2)));
cp =  ((Cn_n*X_n)+(Cn_b*X_b)+(c*x))/(Cn_n + Cn_b + c);

margin_final = (cp-Cg)/D_nos;

end

        function [C,Ceq] = NONLCON(app,X)
C = [];
% Constants (all dimensions in meters):
L_n = 0.548;    % Nose Length
F_r = 0.406;    % Fin Root Chord
F_t = 0.127;    % Fin Tip Chord
F_s = 0.152;  % Fin Semi-Span
S = 0.100;      % Sweep Distance
L_r = 5.844;  % Rocket Length
L_z = 0.0762;   % Nozzle Length
N = 3;          % # of fins
Cn_n = 0.5;     % Nose Cone Coefficient
t = 0.00635;    % Max Fin Root Thickness
X_tc = 0.00635; % Distance from Fin Leading Edge to Max Thickness
L_red = 0.05995;% Length Reduction @ back
D_noz = 0.08204;% Nozzle Diameter
D_nos = 0.140;  % Nose Base Diameter
D_end = 0.1077; % End Diameter
F_w = 0.00381;    % Fin Width (Thickness)
F_fl = 0.21895; % Fin Front Length
Cg = 3.372354308; %Centre of Gravity 

% X (distance from nose tip to component's Cp):
% NOTE: Normal Body and Shoulder Forces are neglected 
X_n = L_n/2;
X_b = L_r - L_red + (L_red/3)*(1+1/(1+D_nos/D_end));

% Component Coefficient of Normal Force (Cn)
Cn_n = 2;
Cn_b = 2*(((D_end/D_nos)^2)-1);

%Optimization Variables = X(1) = a, X(2) = b, X(3) = m, X(4) = s

% Using default values for all other variables,
% % we can cast final Cp position as cp(a,b,m,s):
mid =  X(4)/cos(atan((X(3)+(X(2)/2)-(X(1)/2))/X(4)));
l =  (L_r-X(1))-L_red;
int = 1+ (D_nos/2)/(X(4)+(D_nos/2));
c =  int*(4*N*(X(4)/D_nos)^2)/(1+sqrt(1+(2*mid/(X(1)+X(2)))^2));
x =  l+ (X(3)*(X(1)+2*X(2)))/(3*(X(1)+X(2))) + (1/6)*(X(1)+X(2)-(X(1)*X(2))/(X(1)+X(2)));
cp =  ((Cn_n*X_n)+(Cn_b*X_b)+(c*x))/(Cn_n + Cn_b + c);

margin = (cp-Cg)/D_nos;
Ceq = [margin-app.StabilityMarginEditField.Value];

end


        function drag = DRAG(app,X)
% Constants (all dimensions in meters):
L_n = 0.548;    % Nose Length
F_r = 0.406;    % Fin Root Chord
F_t = 0.127;    % Fin Tip Chord
F_s = 0.152;    % Fin Semi-Span
S = 0.100;      % Sweep Distance
L_r = 5.844;    % Rocket Length
L_z = 0.0762;   % Nozzle Length
N = 3;          % # of fins
Cn_n = 0.5;     % Nose Cone Coefficient
t = 0.00635;    % Max Fin Root Thickness
X_tc = 0.00635; % Distance from Fin Leading Edge to Max Thickness
L_red = 0.05995;% Length Reduction @ back
D_noz = 0.08204;% Nozzle Diameter
D_nos = 0.140;  % Nose Base Diameter
D_end = 0.1077; % End Diameter
F_w = 0.00381;  % Fin Thickness
F_fl = 0.21895; % Fin Front Length
Cg = 3.372354308; %Centre of Gravity 
% Geometric Dimensions
Afe = (1/2)*(X(1)+X(2))*X(4)
Afp = (1/2)*D_nos*X(1) + Afe; % Fin Planform Area (+ Body)
mid =  X(4)/cos(atan((X(3)+(X(2)/2)-(X(1)/2))/X(4))); % Midchord

%Reynolds Number
rho = 1.225 ;%sea level, 15degC (ISA) [kg/m^3]
Ma = 340.3 ;%sea level, 15degC [m/s]
V = 0.85*Ma;
mu = 1.802*(10^-5); % 15degC [kg/m*s]
Re = (rho*V*mid)/mu; % 1,072,936.44 > Re_c = 5x10^5

B = (5*(10^5))*((0.074/(Re^(1/5)))-(1.328/sqrt(Re)));
Cff = (0.074/((Re)^(1/5)))-(B/Re);
Cdf = 2*Cff*(1+2*(F_w/mid))*(4*3*Afp/(pi*(D_nos^2))) 

Cdi = 2*Cff*(1+2*(F_w/mid))*(4*3*(Afp-Afe)/(pi*(D_nos^2)))

drag = Cdf + Cdi;

end
            
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: MachEditField
        function MachEditFieldValueChanged(app, event)
            if app.StabilityMarginEditField.Value ~= 0
                if app.MachEditField.Value ~= 0
                    result_fun = test(app);
                end
            end
            
        end

        % Value changed function: StabilityMarginEditField
        function StabilityMarginEditFieldValueChanged(app, event)
            if app.MachEditField.Value ~= 0
                if app.StabilityMarginEditField.Value ~= 0
                    result_fun = test(app);
                end
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.XTick = [0.2 0.4 0.6 0.8 1];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = '';
            app.FinDragOptimizerwithConstrainedStabilityMarginLabel = uilabel(app.UIFigure);
            app.FinDragOptimizerwithConstrainedStabilityMarginLabel.Text = 'Fin Drag Optimizer with Constrained Stability Margin';
            app.MachEditFieldLabel = uilabel(app.UIFigure);
            app.MachEditFieldLabel.Text = 'Mach #:';
            app.MachEditField = uieditfield(app.UIFigure, 'numeric');
            app.MachEditField.Limits = [0 Inf];
            app.MachEditField.ValueChangedFcn = createCallbackFcn(app, @MachEditFieldValueChanged, true);
            app.MachEditField.Value = 1;
            app.ReynoldsEditFieldLabel = uilabel(app.UIFigure);
            app.ReynoldsEditFieldLabel.Text = 'Reynold''s #:';
            app.ReynoldsField = uieditfield(app.UIFigure, 'numeric');
            app.ReynoldsField.Editable = 'off';
            app.OUTPUTSLabel = uilabel(app.UIFigure);
            app.OUTPUTSLabel.Text = 'OUTPUTS';
            app.INPUTSLabel = uilabel(app.UIFigure);
            app.INPUTSLabel.Text = 'INPUTS';
            app.FinDragCoefficientEditFieldLabel = uilabel(app.UIFigure);
            app.FinDragCoefficientEditFieldLabel.Text = 'Fin Drag Coefficient:';
            app.FinDragCoefficientField = uieditfield(app.UIFigure, 'numeric');
            app.RootChordmEditFieldLabel = uilabel(app.UIFigure);
            app.RootChordmEditFieldLabel.Text = 'Root Chord [m]:';
            app.RootChordField = uieditfield(app.UIFigure, 'numeric');
            app.RootChordField.Editable = 'off';
            app.TipChordmEditFieldLabel = uilabel(app.UIFigure);
            app.TipChordmEditFieldLabel.Text = 'Tip Chord [m]:';
            app.TipChordField = uieditfield(app.UIFigure, 'numeric');
            app.TipChordField.Editable = 'off';
            app.SweepDistancemEditFieldLabel = uilabel(app.UIFigure);
            app.SweepDistancemEditFieldLabel.Text = 'Sweep Distance [m]:';
            app.SweepDistanceField = uieditfield(app.UIFigure, 'numeric');
            app.SweepDistanceField.Editable = 'off';
            app.SemiSpanmEditFieldLabel = uilabel(app.UIFigure);
            app.SemiSpanmEditFieldLabel.Text = 'Semi-Span [m]:';
            app.SemiSpanField = uieditfield(app.UIFigure, 'numeric');
            app.SemiSpanField.Editable = 'off';
            app.StabilityMarginEditFieldLabel = uilabel(app.UIFigure);
            app.StabilityMarginEditFieldLabel.Text = 'Stability Margin:';
            app.StabilityMarginEditField = uieditfield(app.UIFigure, 'numeric');
            app.StabilityMarginEditField.Limits = [0 Inf];
            app.StabilityMarginEditField.ValueChangedFcn = createCallbackFcn(app, @StabilityMarginEditFieldValueChanged, true);
            app.StabilityMarginEditField.Value = 4;
            app.FinExposedAreaEditFieldLabel = uilabel(app.UIFigure);
            app.FinExposedAreaEditFieldLabel.Text = 'Fin Exposed Area:';
            app.FinExposedAreaEditField = uieditfield(app.UIFigure, 'numeric');
            app.FinExposedAreaEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = finoptimizerACcopy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end